classdef Specanalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrumAnalyzerUIFigure       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SpectrumAnalyzerTab            matlab.ui.container.Tab
        examplesDropDownLabel          matlab.ui.control.Label
        examplesDropDown               matlab.ui.control.DropDown
        PlotCalculateButton            matlab.ui.control.Button
        NFFTDropDownLabel              matlab.ui.control.Label
        NFFTDropDown                   matlab.ui.control.DropDown
        SamplingFrequancyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequancyEditField     matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        NorRBWSwitch                   matlab.ui.control.Switch
        RBWEditFieldLabel              matlab.ui.control.Label
        RBWEditField                   matlab.ui.control.NumericEditField
        FreqDomainPlotScaleDropDownLabel  matlab.ui.control.Label
        FreqDomainPlotScaleDropDown    matlab.ui.control.DropDown
        TabGroup2                      matlab.ui.container.TabGroup
        MagnitudeandPhaseTab           matlab.ui.container.Tab
        FreqDomainMagUIAxes            matlab.ui.control.UIAxes
        FreqDomainPhaseUIAxes          matlab.ui.control.UIAxes
        RealandImaginaryTab            matlab.ui.container.Tab
        FreqDomainRealUIAxes           matlab.ui.control.UIAxes
        FreqDomainImagUIAxes           matlab.ui.control.UIAxes
        TimeDomainUIAxes               matlab.ui.control.UIAxes
        UsedRBWEditFieldLabel          matlab.ui.control.Label
        UsedRBWEditField               matlab.ui.control.NumericEditField
        LowerLimitDropDownLabel        matlab.ui.control.Label
        LowerLimitDropDown             matlab.ui.control.DropDown
        FrequencyspanLabel             matlab.ui.control.Label
        UpperLimitDropDownLabel        matlab.ui.control.Label
        UpperLimitDropDown             matlab.ui.control.DropDown
        LowerLimitmustbesmallerthanUpperLimitLabel  matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        AudioFileEditFieldLabel        matlab.ui.control.Label
        AudioFileEditField             matlab.ui.control.EditField
        ExampleOrFileSwitch            matlab.ui.control.Switch
        WindowTypeDropDownLabel        matlab.ui.control.Label
        WindowTypeDropDown             matlab.ui.control.DropDown
        WindowLengthDropDownLabel      matlab.ui.control.Label
        WindowLengthDropDown           matlab.ui.control.DropDown
        RMSAveragingOrderSpinnerLabel  matlab.ui.control.Label
        RMSAveragingOrderSpinner       matlab.ui.control.Spinner
        CalculationTab                 matlab.ui.container.Tab
        SignalPowerEditFieldLabel      matlab.ui.control.Label
        SignalPowerEditField           matlab.ui.control.EditField
        PeakFrequancyinHzEditFieldLabel  matlab.ui.control.Label
        PeakFrequancyinHzEditField     matlab.ui.control.EditField
        PeakMagnitudeEditFieldLabel    matlab.ui.control.Label
        PeakMagnitudeEditField         matlab.ui.control.EditField
        WithinTheChosenfrequencyspanLabel  matlab.ui.control.Label
        AfterRMSUIAxes                 matlab.ui.control.UIAxes
        BeforeRMSUIAxes                matlab.ui.control.UIAxes
        ComparisonTab                  matlab.ui.container.Tab
        SignalDropDownLabel            matlab.ui.control.Label
        SignalDropDown                 matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField     matlab.ui.control.NumericEditField
        COMPAREButton                  matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes3_2                      matlab.ui.control.UIAxes
        NFFTDropDown_2Label            matlab.ui.control.Label
        NFFTDropDown_2                 matlab.ui.control.DropDown
        NFFTDropDown_3Label            matlab.ui.control.Label
        NFFTDropDown_3                 matlab.ui.control.DropDown
        WindowTypeDropDown_2Label      matlab.ui.control.Label
        WindowTypeDropDown_2           matlab.ui.control.DropDown
        WindowTypeDropDown_3Label      matlab.ui.control.Label
        WindowTypeDropDown_3           matlab.ui.control.DropDown
        WindowLengthDropDown_2Label    matlab.ui.control.Label
        WindowLengthDropDown_2         matlab.ui.control.DropDown
        WindowLengthDropDown_3Label    matlab.ui.control.Label
        WindowLengthDropDown_3         matlab.ui.control.DropDown
    end

    % A property to handle the audio file
    properties (Access = private)
        Audio 
    end
    

    methods (Access = private)

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Button pushed function: PlotCalculateButton
        function PlotCalculateButtonPushed(app, event)

        %choose to enter N or to enter RBW    
        value = app.NorRBWSwitch.Value;
        fs = app.SamplingFrequancyEditField.Value; %% sampling frequancies on either ways
            if value == 'N'    %choice to enter value of N
                N = str2double(app.NFFTDropDown.Value);
            elseif value =='R' %choice to enter Value RBW 
               RBW = app.RBWEditField.Value;
               N = fs/RBW; %calculating N from RBW
               N =pow2(round(log2(N))); %%rounding N to the nearest power of 2
            end
            UsedRBW = fs/N;  %Calculating RBW from N
            app.UsedRBWEditField.Value = UsedRBW; %Displaying the used RBW
            %n= 0:1:N-1; %time domain indices
            
            %%Running Examples
            if app.ExampleOrFileSwitch.Value == 'E' 
                if app.examplesDropDown.Value == "Cosine+Sine Wave"
                    n= 0:1:N-1;
                    xTimeDomain(n+1)= 0.5*sin(2*pi*n/fs)+cos(2*pi*n/fs);
                elseif (app.examplesDropDown.Value == "Saw Tooth Wave")
                    n= 0:1:N-1;
                    xTimeDomain(n+1) = sawtooth(2*pi*n/(fs));
                   
                end
            
            % Loading Audio File
            elseif app.ExampleOrFileSwitch.Value == 'F'
                n= 0:N-1;
                xTimeDomain= (app.Audio)';
        
            end
            %selecting window
            if app.WindowTypeDropDown.Value== "Rectangular"
                WL= str2double(app.WindowLengthDropDown.Value);
                xTimeDomain= fixdimension(xTimeDomain,WL);
                
              elseif app.WindowTypeDropDown.Value== "Triangular"
                  WL= str2double(app.WindowLengthDropDown.Value);
                  xTimeDomain= fixdimension(xTimeDomain,WL);
                  xTimeDomain= xTimeDomain.*triang(WL)';
              elseif app.WindowTypeDropDown.Value== "Hamming"
                  WL= str2double(app.WindowLengthDropDown.Value);
                  xTimeDomain= fixdimension(xTimeDomain,WL);
                  xTimeDomain= xTimeDomain.*hamming(WL)';
              elseif app.WindowTypeDropDown.Value== "Hanning"
                  WL= str2double(app.WindowLengthDropDown.Value);
                  xTimeDomain= fixdimension(xTimeDomain,WL);
                  xTimeDomain= xTimeDomain.*hann(WL)';
            end
            
            %Perform FFT
            xFreqDomain = radix2fft(xTimeDomain,N);
            xFreqDomainMagnitude = abs(xFreqDomain);
            xFreqDomainPhase = angle(xFreqDomain);
            xFreqDomainReal = real(xFreqDomain);
            xFreqDomainImaginary = imag(xFreqDomain);
            %Perform RMS Averaging
            nSubArray = app.RMSAveragingOrderSpinner.Value;
            xFreqDomainAfterRMS = RMSaverage(xTimeDomain,nSubArray,N);
            
            %Choose frequancy spab
            FreqDomainIndices = n.*(fs/N);
               FreqIndeciesLowLim = str2double(app.LowerLimitDropDown.Value);
              FreqIndeciesUpLim = str2double(app.UpperLimitDropDown.Value);
              if FreqIndeciesLowLim > FreqIndeciesUpLim
                 app.LowerLimitmustbesmallerthanUpperLimitLabel.Visible = 'on';
              elseif FreqIndeciesLowLim < FreqIndeciesUpLim
                 FreqDomainIndices = FreqDomainIndices(:,N*FreqIndeciesLowLim+1:N*FreqIndeciesUpLim);
                 xFreqDomainMagnitude = xFreqDomainMagnitude(:,N*FreqIndeciesLowLim+1:N*FreqIndeciesUpLim);
                 xFreqDomainPhase = xFreqDomainPhase(:,N*FreqIndeciesLowLim+1:N*FreqIndeciesUpLim);
                 xFreqDomainReal = xFreqDomainReal(:,N*FreqIndeciesLowLim+1:N*FreqIndeciesUpLim);
                 xFreqDomainImaginary = xFreqDomainImaginary(:,N*FreqIndeciesLowLim+1:N*FreqIndeciesUpLim);
                 xFreqDomainAfterRMS = xFreqDomainAfterRMS(:,N*FreqIndeciesLowLim+1:N*FreqIndeciesUpLim);
              end
              
              % Choose plot scale and Frequancy Plotting 
            FreqPlotScale = app.FreqDomainPlotScaleDropDown.Value;
            if strcmp(FreqPlotScale,'Linear')
                app.FreqDomainMagUIAxes.YScale = 'linear';
                app.FreqDomainPhaseUIAxes.YScale = 'linear';
                app.FreqDomainRealUIAxes.YScale = 'linear';
                app.FreqDomainImagUIAxes.YScale = 'linear';
                stem(app.FreqDomainMagUIAxes ,FreqDomainIndices,xFreqDomainMagnitude);
            stem(app.FreqDomainPhaseUIAxes ,FreqDomainIndices,xFreqDomainPhase);
            stem(app.FreqDomainRealUIAxes ,FreqDomainIndices,xFreqDomainReal);
            stem(app.FreqDomainImagUIAxes ,FreqDomainIndices,xFreqDomainImaginary);
            elseif strcmp(FreqPlotScale,'Log')
                xLogFreqDomainMagnitude = 20*log10(xFreqDomainMagnitude);
                xLogFreqDomainPhase = 20*log10(xFreqDomainPhase);
                xLogFreqDomainReal = 20*log10(xFreqDomainReal);
                xLogFreqDomainImaginary =20*log10(xFreqDomainImaginary);
                stem(app.FreqDomainMagUIAxes ,FreqDomainIndices,xLogFreqDomainMagnitude);
                stem(app.FreqDomainPhaseUIAxes ,FreqDomainIndices,xLogFreqDomainPhase);
                stem(app.FreqDomainRealUIAxes ,FreqDomainIndices,xLogFreqDomainReal);
                stem(app.FreqDomainImagUIAxes ,FreqDomainIndices,xLogFreqDomainImaginary);
            end
            
            % Time Domain Plotting
            TL=length(xTimeDomain);
            n2=1:TL;
            stem(app.TimeDomainUIAxes,n2,xTimeDomain);
            
            
            %% calculation part
            % Find peak within the Choosen Frequancy Span
            peak = xFreqDomainMagnitude(1);
            peakFrequancy = FreqDomainIndices(1);
            for i=1:length(FreqDomainIndices)
              if peak < xFreqDomainMagnitude(i)
                  peak = xFreqDomainMagnitude(i);
                  peakFrequancy = FreqDomainIndices(i);
              end
            end
            app.PeakMagnitudeEditField.Value = num2str(peak);
            app.PeakFrequancyinHzEditField.Value = num2str(peakFrequancy);
            
            %Calculate the power within the Choosen Frequancy Span
            power = 0;
            for i=1:length(FreqDomainIndices)
                power = power + xFreqDomainMagnitude(i)^2;
            end
            app.SignalPowerEditField.Value = num2str(power);
            
            %Plot Frequancy Mag After RMS Averaging
            stem(app.AfterRMSUIAxes ,FreqDomainIndices,xFreqDomainAfterRMS);
        end

        % Value changed function: NorRBWSwitch
        function NorRBWSwitchValueChanged(app, event)
            value = app.NorRBWSwitch.Value;
            if value == 'N'
               app.NFFTDropDown.Enable = 'on';
               app.RBWEditField.Enable = 'off';
            elseif value =='R'
               app.NFFTDropDown.Enable = 'off';
               app.RBWEditField.Enable = 'on';
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename, path]= uigetfile('*.wav', 'file selector');
            if ~ischar(filename)
                return;  % User aborted the file selection
            end
            file = fullfile(path, filename);
            
            app.AudioFileEditField.Value=file;
            [app.Audio x]= audioread(file);
        end

        % Value changed function: ExampleOrFileSwitch
        function ExampleOrFileSwitchValueChanged(app, event)
            value = app.ExampleOrFileSwitch.Value;
             if value == 'E'
                app.examplesDropDown.Enable= 'on';
                app.BrowseButton.Enable= 'off';
            elseif value == 'F'
                app.examplesDropDown.Enable = 'off';
                app.BrowseButton.Enable= 'on';
            end
        end

        % Button pushed function: COMPAREButton
        function COMPAREButtonPushed(app, event)
            fs2 = 1; %% sampling frequancies on either ways
             N2 = str2double(app.NFFTDropDown_2.Value);
             N3 = str2double(app.NFFTDropDown_3.Value);
            
            %Running Examples
            if app.SignalDropDown.Value == "Cosine+Sine Wave"
                n2= 0:1:N2-1;
                xTimeDomain2(n2+1) = 0.5*sin(2*pi*n2/(fs2*N2))+cos(2*pi*n2/(fs2*N2));
            elseif (app.SignalDropDown.Value == "Saw Tooth Wave")
                n2= 0:1:N2-1;
                xTimeDomain2(n2+1) = sawtooth(2*pi*n2/(fs2*N2));
              
            end
            
            if app.SignalDropDown.Value == "Cosine+Sine Wave"
                n3= 0:1:N3-1;
                xTimeDomain3(n3+1) = 0.5*sin(2*pi*n3/(fs2*N3))+cos(2*pi*n3/(fs2*N3));
            elseif (app.SignalDropDown.Value == "Saw Tooth Wave")
                n3= 0:1:N3-1;
                xTimeDomain3(n3+1) = sawtooth(2*pi*n3/(fs2*N3));
              
            end

            %selecting window
            if app.WindowTypeDropDown_2.Value== "Rectangular"
                WL= str2double(app.WindowLengthDropDown_2.Value);
                xTimeDomain2= fixdimension(xTimeDomain2,WL);
                
              elseif app.WindowTypeDropDown_2.Value== "Triangular"
                  WL= str2double(app.WindowLengthDropDown_2.Value);
                  xTimeDomain2= fixdimension(xTimeDomain2,WL);
                  xTimeDomain2= xTimeDomain2.*triang(WL)';
              elseif app.WindowTypeDropDown_2.Value== "Hamming"
                  WL= str2double(app.WindowLengthDropDown_2.Value);
                  xTimeDomain2= fixdimension(xTimeDomain2,WL);
                  xTimeDomain2= xTimeDomain2.*hamming(WL)';
              elseif app.WindowTypeDropDown_2.Value== "Hanning"
                  WL= str2double(app.WindowLengthDropDown_2.Value);
                  xTimeDomain2= fixdimension(xTimeDomain2,WL);
                  xTimeDomain2= xTimeDomain2.*hann(WL)';
            end
            
             if app.WindowTypeDropDown_3.Value== "Rectangular"
                WL= str2double(app.WindowLengthDropDown_3.Value);
                xTimeDomain3= fixdimension(xTimeDomain3,WL);
                
              elseif app.WindowTypeDropDown_3.Value== "Triangular"
                  WL= str2double(app.WindowLengthDropDown_3.Value);
                  xTimeDomain3= fixdimension(xTimeDomain3,WL);
                  xTimeDomain3= xTimeDomain3.*triang(WL)';
              elseif app.WindowTypeDropDown_3.Value== "Hamming"
                  WL= str2double(app.WindowLengthDropDown_3.Value);
                  xTimeDomain3= fixdimension(xTimeDomain3,WL);
                  xTimeDomain3= xTimeDomain3.*hamming(WL)';
              elseif app.WindowTypeDropDown_3.Value== "Hanning"
                  WL= str2double(app.WindowLengthDropDown_3.Value);
                  xTimeDomain3= fixdimension(xTimeDomain3,WL);
                  xTimeDomain3= xTimeDomain3.*hann(WL)';
            end
            
          %  xTimeDomain(n+1) = 0.5*sin(2*pi*n/(fs*N))+cos(2*pi*n/(fs*N));
            xFreqDomain2 = radix2fft(xTimeDomain2,N2);
            xFreqDomainMagnitude2 = abs(xFreqDomain2);
            
            TL=length(xTimeDomain2);
            n4=1:TL;
            stem(app.UIAxes3,n4,xTimeDomain2);
            stem(app.UIAxes2 , n2*(fs2/N2) , xFreqDomainMagnitude2);
            
            xFreqDomain3 = radix2fft(xTimeDomain3,N3);
            xFreqDomainMagnitude3 = abs(xFreqDomain3);
            
            TL=length(xTimeDomain3);
            n5=1:TL;
            stem(app.UIAxes3_2,n5,xTimeDomain3);
            stem(app.UIAxes2_2 , n3*(fs2/N3) , xFreqDomainMagnitude3);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == "Same Signal Different N"
                app.NFFTDropDown_3.Enable = 'on';
                app.WindowTypeDropDown_3.Enable = 'off';
                app.WindowLengthDropDown_3.Enable = 'off';
                app.NFFTDropDown_2.Enable = 'on';
                app.WindowTypeDropDown_2.Enable = 'off';
                app.WindowLengthDropDown_2.Enable = 'off';
                app.WindowLengthDropDown_3.Value=app.WindowLengthDropDown_2.Value;
                app.WindowTypeDropDown_3.Value= app.WindowTypeDropDown_2.Value;
            elseif value == "Same Signal, Different Windows"
                app.NFFTDropDown_3.Enable = 'off';
                app.WindowTypeDropDown_3.Enable = 'on';
                app.WindowLengthDropDown_3.Enable = 'off';
                app.NFFTDropDown_2.Enable = 'off';
                app.WindowTypeDropDown_2.Enable = 'on';
                app.WindowLengthDropDown_2.Enable = 'off';
                app.WindowLengthDropDown_3.Value=app.WindowLengthDropDown_2.Value;
                app.NFFTDropDown_3.Value = app.NFFTDropDown_2.Value;
            elseif value == "Same Window, Different Lengths"
                app.NFFTDropDown_3.Enable = 'off';
                app.WindowTypeDropDown_3.Enable = 'off';
                app.WindowLengthDropDown_3.Enable = 'on'; 
                app.NFFTDropDown_2.Enable = 'off';
                app.WindowTypeDropDown_2.Enable = 'off';
                app.WindowLengthDropDown_2.Enable = 'on'; 
                app.NFFTDropDown_3.Value = app.NFFTDropDown_2.Value;
                app.WindowTypeDropDown_3.Value= app.WindowTypeDropDown_2.Value;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrumAnalyzerUIFigure = uifigure;
            app.SpectrumAnalyzerUIFigure.Name = 'Spectrum Analyzer';
            app.TabGroup = uitabgroup(app.SpectrumAnalyzerUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SpectrumAnalyzerTab = uitab(app.TabGroup);
            app.SpectrumAnalyzerTab.Title = 'Spectrum Analyzer';
            app.examplesDropDownLabel = uilabel(app.SpectrumAnalyzerTab);
            app.examplesDropDownLabel.Text = 'examples';
            app.examplesDropDown = uidropdown(app.SpectrumAnalyzerTab);
            app.examplesDropDown.Items = {'Cosine+Sine Wave', 'Saw Tooth Wave'};
            app.examplesDropDown.Value = 'Cosine+Sine Wave';
            app.PlotCalculateButton = uibutton(app.SpectrumAnalyzerTab, 'push');
            app.PlotCalculateButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCalculateButtonPushed, true);
            app.PlotCalculateButton.Text = 'Plot & Calculate';
            app.NFFTDropDownLabel = uilabel(app.SpectrumAnalyzerTab);
            app.NFFTDropDownLabel.Text = 'N-FFT';
            app.NFFTDropDown = uidropdown(app.SpectrumAnalyzerTab);
            app.NFFTDropDown.Items = {'1', '2', '4', '8', '16', '32', '64', '128', '256', '512', '1k', '2k', '4k', '8k', '16k', '32k', '64k'};
            app.NFFTDropDown.ItemsData = {'1', '2', '4', '8', '16', '32', '64', '128', '256', '512', '1024', '2048', '4096', '8192', '16384', '32768', '65536'};
            app.NFFTDropDown.Value = '1';
            app.SamplingFrequancyEditFieldLabel = uilabel(app.SpectrumAnalyzerTab);
            app.SamplingFrequancyEditFieldLabel.Text = 'Sampling Frequancy';
            app.SamplingFrequancyEditField = uieditfield(app.SpectrumAnalyzerTab, 'numeric');
            app.SamplingFrequancyEditField.Limits = [0 Inf];
            app.SamplingFrequancyEditField.Value = 1;
            app.Label = uilabel(app.SpectrumAnalyzerTab);
            app.Label.Text = '';
            app.NorRBWSwitch = uiswitch(app.SpectrumAnalyzerTab, 'slider');
            app.NorRBWSwitch.Items = {'Choose N', 'Choose RBW'};
            app.NorRBWSwitch.ItemsData = {'N', 'R'};
            app.NorRBWSwitch.ValueChangedFcn = createCallbackFcn(app, @NorRBWSwitchValueChanged, true);
            app.NorRBWSwitch.Value = 'N';
            app.RBWEditFieldLabel = uilabel(app.SpectrumAnalyzerTab);
            app.RBWEditFieldLabel.Text = 'RBW';
            app.RBWEditField = uieditfield(app.SpectrumAnalyzerTab, 'numeric');
            app.RBWEditField.Limits = [0 Inf];
            app.RBWEditField.Enable = 'off';
            app.FreqDomainPlotScaleDropDownLabel = uilabel(app.SpectrumAnalyzerTab);
            app.FreqDomainPlotScaleDropDownLabel.Text = 'Freq Domain Plot Scale';
            app.FreqDomainPlotScaleDropDown = uidropdown(app.SpectrumAnalyzerTab);
            app.FreqDomainPlotScaleDropDown.Items = {'Linear', 'Log'};
            app.FreqDomainPlotScaleDropDown.ItemsData = {'Linear', 'Log'};
            app.FreqDomainPlotScaleDropDown.Value = 'Linear';
            app.TabGroup2 = uitabgroup(app.SpectrumAnalyzerTab);
            app.MagnitudeandPhaseTab = uitab(app.TabGroup2);
            app.MagnitudeandPhaseTab.Title = 'Magnitude and Phase';
            app.FreqDomainMagUIAxes = uiaxes(app.MagnitudeandPhaseTab);
            title(app.FreqDomainMagUIAxes, 'Magnitude of Frequancy Spectrum ')
            xlabel(app.FreqDomainMagUIAxes, 'Frequancy in Hz')
            ylabel(app.FreqDomainMagUIAxes, 'Magnitude')
            app.FreqDomainPhaseUIAxes = uiaxes(app.MagnitudeandPhaseTab);
            title(app.FreqDomainPhaseUIAxes, 'Phase of Frequancy Spectrum ')
            xlabel(app.FreqDomainPhaseUIAxes, 'Frequancy in Hz')
            ylabel(app.FreqDomainPhaseUIAxes, 'Phase in rad')
            app.RealandImaginaryTab = uitab(app.TabGroup2);
            app.RealandImaginaryTab.Title = 'Real and Imaginary';
            app.FreqDomainRealUIAxes = uiaxes(app.RealandImaginaryTab);
            title(app.FreqDomainRealUIAxes, 'Real Part of Frequancy Spectrum ')
            xlabel(app.FreqDomainRealUIAxes, 'Frequancy in Hz')
            ylabel(app.FreqDomainRealUIAxes, 'Real Part')
            app.FreqDomainImagUIAxes = uiaxes(app.RealandImaginaryTab);
            title(app.FreqDomainImagUIAxes, 'Imaginary Part of Frequancy Spectrum ')
            xlabel(app.FreqDomainImagUIAxes, 'Frequancy in Hz')
            ylabel(app.FreqDomainImagUIAxes, 'Imaginary part')
            app.TimeDomainUIAxes = uiaxes(app.SpectrumAnalyzerTab);
            title(app.TimeDomainUIAxes, 'Time Domain')
            xlabel(app.TimeDomainUIAxes, 'n')
            ylabel(app.TimeDomainUIAxes, 'x(n)')
            app.UsedRBWEditFieldLabel = uilabel(app.SpectrumAnalyzerTab);
            app.UsedRBWEditFieldLabel.Text = 'Used RBW';
            app.UsedRBWEditField = uieditfield(app.SpectrumAnalyzerTab, 'numeric');
            app.UsedRBWEditField.Editable = 'off';
            app.LowerLimitDropDownLabel = uilabel(app.SpectrumAnalyzerTab);
            app.LowerLimitDropDownLabel.Text = 'Lower Limit';
            app.LowerLimitDropDown = uidropdown(app.SpectrumAnalyzerTab);
            app.LowerLimitDropDown.Items = {'0', 'fs/4', 'fs/2', '3*fs/4'};
            app.LowerLimitDropDown.ItemsData = {'0', '0.25', '0.5', '0.75'};
            app.LowerLimitDropDown.Value = '0';
            app.FrequencyspanLabel = uilabel(app.SpectrumAnalyzerTab);
            app.FrequencyspanLabel.Text = 'Frequency span';
            app.UpperLimitDropDownLabel = uilabel(app.SpectrumAnalyzerTab);
            app.UpperLimitDropDownLabel.Text = 'Upper Limit';
            app.UpperLimitDropDown = uidropdown(app.SpectrumAnalyzerTab);
            app.UpperLimitDropDown.Items = {'fs', '3*fs/4', 'fs/2', 'fs/4'};
            app.UpperLimitDropDown.ItemsData = {'1', '0.75', '0.5', '0.25'};
            app.UpperLimitDropDown.Value = '1';
            app.LowerLimitmustbesmallerthanUpperLimitLabel = uilabel(app.SpectrumAnalyzerTab);
            app.LowerLimitmustbesmallerthanUpperLimitLabel.Visible = 'off';
            app.LowerLimitmustbesmallerthanUpperLimitLabel.Text = 'Lower Limit must be smaller than Upper Limit';
            app.BrowseButton = uibutton(app.SpectrumAnalyzerTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.AudioFileEditFieldLabel = uilabel(app.SpectrumAnalyzerTab);
            app.AudioFileEditFieldLabel.Enable = 'off';
            app.AudioFileEditFieldLabel.Text = 'Audio File';
            app.AudioFileEditField = uieditfield(app.SpectrumAnalyzerTab, 'text');
            app.AudioFileEditField.Editable = 'off';
            app.AudioFileEditField.Enable = 'off';
            app.ExampleOrFileSwitch = uiswitch(app.SpectrumAnalyzerTab, 'slider');
            app.ExampleOrFileSwitch.Items = {'', ''};
            app.ExampleOrFileSwitch.ItemsData = {'E', 'F'};
            app.ExampleOrFileSwitch.ValueChangedFcn = createCallbackFcn(app, @ExampleOrFileSwitchValueChanged, true);
            app.ExampleOrFileSwitch.Value = 'E';
            app.WindowTypeDropDownLabel = uilabel(app.SpectrumAnalyzerTab);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowTypeDropDown = uidropdown(app.SpectrumAnalyzerTab);
            app.WindowTypeDropDown.Items = {'Rectangular', 'Hamming', 'Triangular', 'Hanning'};
            app.WindowTypeDropDown.Value = 'Rectangular';
            app.WindowLengthDropDownLabel = uilabel(app.SpectrumAnalyzerTab);
            app.WindowLengthDropDownLabel.Text = 'Window Length';
            app.WindowLengthDropDown = uidropdown(app.SpectrumAnalyzerTab);
            app.WindowLengthDropDown.Items = {'1', '2', '4', '8', '16', '32', '64', '128', '256', '512', '1k', '2k', '4k', '8k', '16k', '32k', '64k'};
            app.WindowLengthDropDown.ItemsData = {'1', '2', '4', '8', '16', '32', '64', '128', '256', '512', '1024', '2048', '4096', '8192', '16384', '32768', '65536'};
            app.WindowLengthDropDown.Value = '1';
            app.RMSAveragingOrderSpinnerLabel = uilabel(app.SpectrumAnalyzerTab);
            app.RMSAveragingOrderSpinnerLabel.Text = 'RMS Averaging Order';
            app.RMSAveragingOrderSpinner = uispinner(app.SpectrumAnalyzerTab);
            app.RMSAveragingOrderSpinner.Limits = [1 Inf];
            app.RMSAveragingOrderSpinner.Value = 1;
            app.CalculationTab = uitab(app.TabGroup);
            app.CalculationTab.Title = 'Calculation ';
            app.SignalPowerEditFieldLabel = uilabel(app.CalculationTab);
            app.SignalPowerEditFieldLabel.Text = 'Signal Power';
            app.SignalPowerEditField = uieditfield(app.CalculationTab, 'text');
            app.SignalPowerEditField.Editable = 'off';
            app.PeakFrequancyinHzEditFieldLabel = uilabel(app.CalculationTab);
            app.PeakFrequancyinHzEditFieldLabel.Text = 'Peak Frequancy in Hz';
            app.PeakFrequancyinHzEditField = uieditfield(app.CalculationTab, 'text');
            app.PeakFrequancyinHzEditField.Editable = 'off';
            app.PeakMagnitudeEditFieldLabel = uilabel(app.CalculationTab);
            app.PeakMagnitudeEditFieldLabel.Text = 'Peak Magnitude';
            app.PeakMagnitudeEditField = uieditfield(app.CalculationTab, 'text');
            app.PeakMagnitudeEditField.Editable = 'off';
            app.WithinTheChosenfrequencyspanLabel = uilabel(app.CalculationTab);
            app.WithinTheChosenfrequencyspanLabel.Text = 'Within The Chosen frequency span:';
            app.AfterRMSUIAxes = uiaxes(app.CalculationTab);
            title(app.AfterRMSUIAxes, 'Frequancy Magnitude after RMS Averaging')
            xlabel(app.AfterRMSUIAxes, 'X')
            ylabel(app.AfterRMSUIAxes, 'Y')
            app.BeforeRMSUIAxes = uiaxes(app.CalculationTab);
            title(app.BeforeRMSUIAxes, 'Frequancy Magnitude Before RMS Averaging')
            xlabel(app.BeforeRMSUIAxes, 'X')
            ylabel(app.BeforeRMSUIAxes, 'Y')
            app.BeforeRMSUIAxes.Visible = 'off';
            app.ComparisonTab = uitab(app.TabGroup);
            app.ComparisonTab.Title = 'Comparison';
            app.SignalDropDownLabel = uilabel(app.ComparisonTab);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.ComparisonTab);
            app.SignalDropDown.Items = {'Cosine+Sine Wave', 'Saw Tooth Wave'};
            app.SignalDropDown.Value = 'Cosine+Sine Wave';
            app.DropDownLabel = uilabel(app.ComparisonTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.ComparisonTab);
            app.DropDown.Items = {'Same Signal Different N', 'Same Signal, Different Windows', 'Same Window, Different Lengths'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Same Signal Different N';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.ComparisonTab);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.ComparisonTab, 'numeric');
            app.COMPAREButton = uibutton(app.ComparisonTab, 'push');
            app.COMPAREButton.ButtonPushedFcn = createCallbackFcn(app, @COMPAREButtonPushed, true);
            app.COMPAREButton.Text = 'COMPARE';
            app.UIAxes2 = uiaxes(app.ComparisonTab);
            title(app.UIAxes2, 'Frequancy Domain')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.ComparisonTab);
            title(app.UIAxes3, 'Time Domain')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes2_2 = uiaxes(app.ComparisonTab);
            title(app.UIAxes2_2, 'Frequancy Domain')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.UIAxes3_2 = uiaxes(app.ComparisonTab);
            title(app.UIAxes3_2, 'Time Domain')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.NFFTDropDown_2Label = uilabel(app.ComparisonTab);
            app.NFFTDropDown_2Label.Text = 'N-FFT';
            app.NFFTDropDown_2 = uidropdown(app.ComparisonTab);
            app.NFFTDropDown_2.Items = {'64', '128', '256', '512', '1k', '2k', '4k', '8k', '16k', '32k', '64k'};
            app.NFFTDropDown_2.ItemsData = {'64', '128', '256', '512', '1024', '2048', '4096', '8192', '16384', '32768', '65536'};
            app.NFFTDropDown_2.Value = '64';
            app.NFFTDropDown_3Label = uilabel(app.ComparisonTab);
            app.NFFTDropDown_3Label.Text = 'N-FFT';
            app.NFFTDropDown_3 = uidropdown(app.ComparisonTab);
            app.NFFTDropDown_3.Items = {'64', '128', '256', '512', '1k', '2k', '4k', '8k', '16k', '32k', '64k'};
            app.NFFTDropDown_3.ItemsData = {'64', '128', '256', '512', '1024', '2048', '4096', '8192', '16384', '32768', '65536'};
            app.NFFTDropDown_3.Value = '64';
            app.WindowTypeDropDown_2Label = uilabel(app.ComparisonTab);
            app.WindowTypeDropDown_2Label.Enable = 'off';
            app.WindowTypeDropDown_2Label.Text = 'Window Type';
            app.WindowTypeDropDown_2 = uidropdown(app.ComparisonTab);
            app.WindowTypeDropDown_2.Items = {'Rectangular', 'Hamming', 'Triangular', 'Hanning'};
            app.WindowTypeDropDown_2.Enable = 'off';
            app.WindowTypeDropDown_2.Value = 'Rectangular';
            app.WindowTypeDropDown_3Label = uilabel(app.ComparisonTab);
            app.WindowTypeDropDown_3Label.Enable = 'off';
            app.WindowTypeDropDown_3Label.Text = 'Window Type';
            app.WindowTypeDropDown_3 = uidropdown(app.ComparisonTab);
            app.WindowTypeDropDown_3.Items = {'Rectangular', 'Hamming', 'Triangular', 'Hanning'};
            app.WindowTypeDropDown_3.Enable = 'off';
            app.WindowTypeDropDown_3.Value = 'Rectangular';
            app.WindowLengthDropDown_2Label = uilabel(app.ComparisonTab);
            app.WindowLengthDropDown_2Label.Enable = 'off';
            app.WindowLengthDropDown_2Label.Text = 'Window Length';
            app.WindowLengthDropDown_2 = uidropdown(app.ComparisonTab);
            app.WindowLengthDropDown_2.Items = {'8', '16', '32', '64', '128', '256', '512', '1k', '2k', '4k', '8k', '16k', '32k', '64k'};
            app.WindowLengthDropDown_2.ItemsData = {'8', '16', '32', '64', '128', '256', '512', '1024', '2048', '4096', '8192', '16384', '32768', '65536'};
            app.WindowLengthDropDown_2.Enable = 'off';
            app.WindowLengthDropDown_2.Value = '8';
            app.WindowLengthDropDown_3Label = uilabel(app.ComparisonTab);
            app.WindowLengthDropDown_3Label.Enable = 'off';
            app.WindowLengthDropDown_3Label.Text = 'Window Length';
            app.WindowLengthDropDown_3 = uidropdown(app.ComparisonTab);
            app.WindowLengthDropDown_3.Items = {'8', '16', '32', '64', '128', '256', '512', '1k', '2k', '4k', '8k', '16k', '32k', '64k'};
            app.WindowLengthDropDown_3.ItemsData = {'8', '16', '32', '64', '128', '256', '512', '1024', '2048', '4096', '8192', '16384', '32768', '65536'};
            app.WindowLengthDropDown_3.Enable = 'off';
            app.WindowLengthDropDown_3.Value = '8';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Specanalysis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectrumAnalyzerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrumAnalyzerUIFigure)
        end
    end
end