classdef conv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConvUIFigure                matlab.ui.Figure
        ChooseSignal1DropDownLabel  matlab.ui.control.Label
        ChooseSignal1DropDown       matlab.ui.control.DropDown
        ChooseSignal2DropDownLabel  matlab.ui.control.Label
        ChooseSignal2DropDown       matlab.ui.control.DropDown
        SignalLabel_1               matlab.ui.control.Label
        SignalLabel_2               matlab.ui.control.Label
        ConvulateButton             matlab.ui.control.Button
        UIAxes_1                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        AmpAEditFieldLabel          matlab.ui.control.Label
        AmpAEditField_1             matlab.ui.control.NumericEditField
        ExpPowermEditFieldLabel     matlab.ui.control.Label
        ExpPowermEditField_1        matlab.ui.control.NumericEditField
        FreqLabel                   matlab.ui.control.Label
        FreqEditField_1             matlab.ui.control.NumericEditField
        ExpPowermEditField_2        matlab.ui.control.NumericEditField
        FreqEditField_2             matlab.ui.control.NumericEditField
        UIAxesResult                matlab.ui.control.UIAxes
        ExpPowermEditFieldLabel_2   matlab.ui.control.Label
        FreqLabel_2                 matlab.ui.control.Label
        AmpAEditField_2Label        matlab.ui.control.Label
        AmpAEditField_2             matlab.ui.control.NumericEditField
        nMaxEditFieldLabel          matlab.ui.control.Label
        nMaxEditField_1             matlab.ui.control.NumericEditField
        nMaxEditField_2Label        matlab.ui.control.Label
        nMaxEditField_2             matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          app.FreqLabel.Text = {['Freq(' char(969)]};
          app.FreqLabel_2.Text = {['Freq(' char(969)]};
        end

        % Button pushed function: ConvulateButton
        function ConvulateButtonPushed(app, event)
            %getting values from user
            signal1 = app.ChooseSignal1DropDown.Value;
            signal2= app.ChooseSignal2DropDown.Value;
            Amp1 = app.AmpAEditField_1.Value;
            ExpPow1 = app.ExpPowermEditField_1.Value;
            Freq1 = app.FreqEditField_1.Value;
            nmax1 = app.nMaxEditField_1.Value;
            Amp2 = app.AmpAEditField_2.Value;
            ExpPow2 = app.ExpPowermEditField_2.Value;
            Freq2 = app.FreqEditField_2.Value;
            nmax2 = app.nMaxEditField_2.Value;
            
            
            %setting the signals to be conv
            %first signal
            n1 = 0:nmax1;
            if (strcmp(signal1, 'sinusoidal'))
                s1 = Amp1 * cos (Freq1* pi * n1);
                n1=linspace(0, 2*pi,nmax1+1);
            elseif (strcmp(signal1, 'Exponential'))
                s1 = Amp1 * exp(ExpPow1 * n1);
            elseif (strcmp(signals1, 'Train of Impulses'))
                s1 = Amp1 * ones(1,length(n1),'double');
            end
            
            %plot s1
           stem (app.UIAxes_1, n1, s1)
            
            %second signal
            n2 = 0:nmax2;
            if (strcmp(signal2, 'sinusoidal'))
                s2 = Amp2 * cos (Freq2* pi * n2);
                n2=linspace(0, 2*pi,nmax2+1 );
            elseif (strcmp(signal2, 'Exponential'))
                s2 = Amp2 * exp(ExpPow2 * n2);
            elseif (strcmp(signal2, 'Train of Impulses'))
                s2 = Amp2 * ones(1,length(n2),'double');
            end
            
            %plot s2
            stem (app.UIAxes_2, n2, s2)
            
            %conv
            S1=[s1,zeros(1,nmax1)]; 
            S2=[s2,zeros(1,nmax2)]; 
            for i=1:nmax1+nmax2 -1
                Result(i)=0;
                for j=1:nmax2
                    if(i-j+1>0)
                        Result(i)= Result(i) +S1(j) * S2(i-j+1);
                    end
                end
            end
            Result;
            
            %plot result
            stem (app.UIAxesResult, Result)    
            
        end

        % Value changed function: FreqEditField_1
        function FreqEditField_1ValueChanged(app, event)
           
        end

        % Value changed function: ChooseSignal1DropDown
        function ChooseSignal1DropDownValueChanged(app, event)
            value = app.ChooseSignal1DropDown.Value;
            if (strcmp(value, 'sinusoidal'))
                app.ExpPowermEditField_1.Visible = 'off';
            elseif (strcmp(value, 'Exponential'))
                app.FreqEditField_1.Visible = 'off';
            elseif (strcmp(value, 'Train of Impulses'))
                app.FreqEditField_1.Visible = 'off';
                app.ExpPowermEditField_1.Visible = 'off';   
            end
            
            
        end

        % Value changed function: ChooseSignal2DropDown
        function ChooseSignal2DropDownValueChanged(app, event)
          value = app.ChooseSignal2DropDown.Value;
            if (strcmp(value, 'sinusoidal'))
                app.ExpPowermEditField_2.Visible = 'off';
            elseif (strcmp(value, 'Exponential'))
                app.FreqEditField_2.Visible = 'off';
            elseif (strcmp(value, 'Train of Impulses'))
                app.FreqEditField_2.Visible = 'off';
                app.ExpPowermEditField_1.Visible = 'off';   
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConvUIFigure = uifigure('Visible', 'off');
            app.ConvUIFigure.Name = 'Conv';
            app.ChooseSignal1DropDownLabel = uilabel(app.ConvUIFigure);
            app.ChooseSignal1DropDownLabel.Text = {'Choose Signal1'; ''};
            app.ChooseSignal1DropDown = uidropdown(app.ConvUIFigure);
            app.ChooseSignal1DropDown.Items = {'N/A', 'sinusoidal', 'Exponential', 'Train of Impulses'};
            app.ChooseSignal1DropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseSignal1DropDownValueChanged, true);
            app.ChooseSignal1DropDown.Value = 'N/A';
            app.ChooseSignal2DropDownLabel = uilabel(app.ConvUIFigure);
            app.ChooseSignal2DropDownLabel.Text = 'Choose Signal2';
            app.ChooseSignal2DropDown = uidropdown(app.ConvUIFigure);
            app.ChooseSignal2DropDown.Items = {'N/A', 'sinusoidal', 'Exponential', 'Train of Impulses'};
            app.ChooseSignal2DropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseSignal2DropDownValueChanged, true);
            app.ChooseSignal2DropDown.Value = 'N/A';
            app.SignalLabel_1 = uilabel(app.ConvUIFigure);
            app.SignalLabel_1.Text = 'First Signal';
            app.SignalLabel_2 = uilabel(app.ConvUIFigure);
            app.SignalLabel_2.Text = {'Second Signal'; ''};
            app.ConvulateButton = uibutton(app.ConvUIFigure, 'push');
            app.ConvulateButton.ButtonPushedFcn = createCallbackFcn(app, @ConvulateButtonPushed, true);
            app.ConvulateButton.Text = 'Convulate';
            app.UIAxes_1 = uiaxes(app.ConvUIFigure);
            title(app.UIAxes_1, 'First Signal')
            xlabel(app.UIAxes_1, 'n')
            ylabel(app.UIAxes_1, 'X(n)')
            app.UIAxes_1.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2 = uiaxes(app.ConvUIFigure);
            title(app.UIAxes_2, 'Second Signal')
            xlabel(app.UIAxes_2, 'n')
            ylabel(app.UIAxes_2, 'X(n)')
            app.AmpAEditFieldLabel = uilabel(app.ConvUIFigure);
            app.AmpAEditFieldLabel.Text = 'Amp (A)';
            app.AmpAEditField_1 = uieditfield(app.ConvUIFigure, 'numeric');
            app.AmpAEditField_1.Value = 1;
            app.ExpPowermEditFieldLabel = uilabel(app.ConvUIFigure);
            app.ExpPowermEditFieldLabel.Text = {'Exp Power (m)'; ''};
            app.ExpPowermEditField_1 = uieditfield(app.ConvUIFigure, 'numeric');
            app.ExpPowermEditField_1.Value = 1;
            app.FreqLabel = uilabel(app.ConvUIFigure);
            app.FreqLabel.Text = 'Freq ()';
            app.FreqEditField_1 = uieditfield(app.ConvUIFigure, 'numeric');
            app.FreqEditField_1.ValueChangedFcn = createCallbackFcn(app, @FreqEditField_1ValueChanged, true);
            app.FreqEditField_1.Value = 0.5;
            app.ExpPowermEditField_2 = uieditfield(app.ConvUIFigure, 'numeric');
            app.ExpPowermEditField_2.Value = 1;
            app.FreqEditField_2 = uieditfield(app.ConvUIFigure, 'numeric');
            app.FreqEditField_2.Value = 0.5;
            app.UIAxesResult = uiaxes(app.ConvUIFigure);
            title(app.UIAxesResult, 'Result')
            xlabel(app.UIAxesResult, 'n')
            ylabel(app.UIAxesResult, 'X(n)')
            app.ExpPowermEditFieldLabel_2 = uilabel(app.ConvUIFigure);
            app.ExpPowermEditFieldLabel_2.Text = {'Exp Power (m)'; ''};
            app.FreqLabel_2 = uilabel(app.ConvUIFigure);
            app.FreqLabel_2.Text = 'Freq ()';
            app.AmpAEditField_2Label = uilabel(app.ConvUIFigure);
            app.AmpAEditField_2Label.Text = 'Amp (A)';
            app.AmpAEditField_2 = uieditfield(app.ConvUIFigure, 'numeric');
            app.AmpAEditField_2.Value = 1;
            app.nMaxEditFieldLabel = uilabel(app.ConvUIFigure);
            app.nMaxEditFieldLabel.Text = 'n-Max';
            app.nMaxEditField_1 = uieditfield(app.ConvUIFigure, 'numeric');
            app.nMaxEditField_1.Value = 25;
            app.nMaxEditField_2Label = uilabel(app.ConvUIFigure);
            app.nMaxEditField_2Label.Text = 'n-Max';
            app.nMaxEditField_2 = uieditfield(app.ConvUIFigure, 'numeric');
            app.nMaxEditField_2.Value = 25;
            % Show the figure after all components are created
            app.ConvUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = conv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConvUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConvUIFigure)
        end
    end
end